classdef InferencePaperApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        NetworkParametersandGroundTruthTab  matlab.ui.container.Tab
        ROCKnown                        matlab.ui.control.UIAxes
        TruePrecisionDistributions      matlab.ui.control.UIAxes
        Heatmap                         matlab.ui.control.UIAxes
        ROCUnknown                      matlab.ui.control.UIAxes
        SynapticMagnitudeSliderLabel    matlab.ui.control.Label
        SynapticMagnitudeSlider         matlab.ui.control.Slider
        SynapticVariabilitySliderLabel  matlab.ui.control.Label
        SynapticVariabilitySlider       matlab.ui.control.Slider
        RandomGraphModelDropDownLabel   matlab.ui.control.Label
        RandomGraphModelDropDown        matlab.ui.control.DropDown
        GenerateButton                  matlab.ui.control.Button
        NumberofNeuronsEditFieldLabel   matlab.ui.control.Label
        NumberofNeuronsEditField        matlab.ui.control.NumericEditField
        ConnectionProbabilitySliderLabel  matlab.ui.control.Label
        ConnectionProbabilitySlider     matlab.ui.control.Slider
        ExcitatoryRatioSliderLabel      matlab.ui.control.Label
        ExcitatoryRatioSlider           matlab.ui.control.Slider
        SynapticRatiosEditFieldLabel_2  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_3  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_4  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_5  matlab.ui.control.Label
        SynapticRatiosEditFieldLabel_6  matlab.ui.control.Label
        SynapticRatiosEE                matlab.ui.control.NumericEditField
        SynapticRatiosIE                matlab.ui.control.NumericEditField
        SynapticRatiosEI                matlab.ui.control.NumericEditField
        AreaUnderEditFieldLabel         matlab.ui.control.Label
        AUROCEE1                        matlab.ui.control.NumericEditField
        AreaUnderEditFieldLabel_2       matlab.ui.control.Label
        AUROCEE2                        matlab.ui.control.NumericEditField
        AUROCII1                        matlab.ui.control.NumericEditField
        AUROCII2                        matlab.ui.control.NumericEditField
        AUROCEI                         matlab.ui.control.NumericEditField
        AUROCIE                         matlab.ui.control.NumericEditField
        AUROCEIIE                       matlab.ui.control.NumericEditField
        AUROCUnlabeled                  matlab.ui.control.NumericEditField
        AreaUnderEditFieldLabel_3       matlab.ui.control.Label
        AreaUnderEditFieldLabel_4       matlab.ui.control.Label
        SimulationTab                   matlab.ui.container.Tab
        AnalysisTab                     matlab.ui.container.Tab
    end

    
    properties (Access = private)
        
    end
    
    properties (Access = public)
        Network; % Random graph of synaptic connectivity
        
        W
        Precision
        BiDirIds
        
        lw = 2;
        fz = 16;
    end
    
    methods (Access = private)
        
        function GenerateERNetwork(app)
            N = app.NumberofNeuronsEditField.Value;
            Ne = round(N*app.ExcitatoryRatioSlider.Value);
            Ni = N - Ne;
            
            psi = [app.SynapticRatiosEE.Value(1), -app.SynapticRatiosEI.Value(1) ; ...
                   app.SynapticRatiosIE.Value(1), -1];
            Jm = app.SynapticMagnitudeSlider.Value * psi / sqrt(N);
            Vm = app.SynapticVariabilitySlider.Value * psi.^2 / N;
            sm = sqrt(Vm);
            
            P = app.ConnectionProbabilitySlider.Value * ones(2);
            
            if (sm(1,1) ~= 0) % use first element to decide
                Jmee_dist = makedist('normal',Jm(1,1),sm(1,1));
                Jmee_tdist = truncate(Jmee_dist,0,Inf); % Dale's Law
                Jmei_dist = makedist('normal',Jm(1,2),sm(1,2));
                Jmei_tdist = truncate(Jmei_dist,-Inf,0); % Dale's Law
                Jmie_dist = makedist('normal',Jm(2,1),sm(2,1));
                Jmie_tdist = truncate(Jmie_dist,0,Inf); % Dale's Law
                Jmii_dist = makedist('normal',Jm(2,2),sm(2,2));
                Jmii_tdist = truncate(Jmii_dist,-Inf,0); % Dale's Law
        
                J = [sparse(random(Jmee_tdist,[Ne Ne]).*binornd(1,P(1,1),Ne,Ne)) ...
                     sparse(random(Jmei_tdist,[Ne Ni]).*binornd(1,P(1,2),Ne,Ni)); ...
                     sparse(random(Jmie_tdist,[Ni Ne]).*binornd(1,P(2,1),Ni,Ne)) ...
                     sparse(random(Jmii_tdist,[Ni Ni]).*binornd(1,P(2,2),Ni,Ni))];
            else
                J = [Jm(1,1) * binornd(1,P(1,1),Ne,Ne) ...
                     Jm(1,2) * binornd(1,P(1,2),Ne,Ni); ...
                     Jm(2,1) * binornd(1,P(2,1),Ni,Ne) ...
                     Jm(2,2) * binornd(1,P(2,2),Ni,Ni)];
            end
            
            J(1:(N+1):end) = 0; % Remove self-loops
            
            app.Network = J;
            app.W = J; % needs to be generalized
            app.Precision = app.W + app.W' - (app.W'*app.W);
            GetBiDirIds(app);
        end


        % Helper function for updating heatmap plot
        function PlotHeatmap(app)
            nHeatMapPlot = min([app.NumberofNeuronsEditField.Value 1e2]);
            
            if (nHeatMapPlot == 1e2)
                imagesc(app.Heatmap,app.Network([1:80 (end-19):end],[1:80 (end-19):end]))
                colormap(app.Heatmap,'hot')
                h = colorbar(app.Heatmap);
            else
                imagesc(app.Heatmap,app.Network(1:nHeatMapPlot,1:nHeatMapPlot))
                colormap(app.Heatmap,'hot')
                h = colorbar(app.Heatmap);
            end
            
            %app.Heatmap.
            app.Heatmap.XLim = [1 nHeatMapPlot];
            app.Heatmap.YLim = [1 nHeatMapPlot];
            %ylabel(h, 'Synaptic Strength')
            
            %colorTitleHandle = get(app.Heatmap,'Title');
            %titleString = 'Synaptic Strength';
            %set(colorTitleHandle ,'String',titleString);
            
            app.Heatmap.Box = 'off';
            app.Heatmap.LineWidth = app.lw;
            app.Heatmap.FontSize = app.fz;
        end


        % Helper function for updating precision distributions plot
        function PlotPrecisionDistributions(app)
            Colors = {[0 0.6 0], ... % EE1
                      [0 0.8 0], ... % EE2
                      [0.3010 0.7450 0.9330], ... % IE
                      [0.6 0 0], ... % II1
                      [0.8 0 0], ... % II2
                      [0.4940 0.1840 0.5560], ... % EI
                      [0 0.4470 0.7410]}; % EIIE
            
            SubTypeMeans = GetEISubTypeMeans(app);
            SubTypeStds = GetEISubTypeStds(app);
            
            % Plot
            numIncr = 1e3;
            
            legendNames = cell(1,1); ii = 1;
            handles = [];
            
            % assumes EE2 has elements...
            XX_EE = linspace(SubTypeMeans.EE0-5*SubTypeStds.EE0, SubTypeMeans.EE2+5*SubTypeStds.EE2, numIncr);
            
            % assumes II2 has elements...
            XX_II = linspace(SubTypeMeans.II2-5*SubTypeStds.II2, SubTypeMeans.II0+5*SubTypeStds.II0, numIncr);
            
            % assumes EE2 has elements...
            XX_EIIE = linspace(SubTypeMeans.EI-5*SubTypeStds.EI, SubTypeMeans.IE+5*SubTypeStds.IE, numIncr);
        
            cla(app.TruePrecisionDistributions)
            if(any( SubTypeMeans.EE0 ))
                EE0Gauss = normpdf(XX_EE,SubTypeMeans.EE0,SubTypeStds.EE0);
                
                h1 = plot(app.TruePrecisionDistributions,XX_EE,EE0Gauss, 'k', 'Linewidth',app.lw);
                hold(app.TruePrecisionDistributions,'on')
                legendNames{ii} = 'e,i \neq e,i';
                handles(ii) = h1;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EE1 ))
                EE1Gauss = normpdf(XX_EE,SubTypeMeans.EE1,SubTypeStds.EE1);
                
                h2 = plot(app.TruePrecisionDistributions,XX_EE,EE1Gauss, 'Color',Colors{1}, 'Linewidth',app.lw);
                legendNames{ii} = 'e \rightarrow e';
                handles(ii) = h2;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EE2 ))
                EE2Gauss = normpdf(XX_EE,SubTypeMeans.EE2,SubTypeStds.EE2);
                
                h3 = plot(app.TruePrecisionDistributions,XX_EE,EE2Gauss, 'Color',Colors{2}, 'Linewidth',app.lw);
                legendNames{ii} = 'e \leftrightarrow e';
                handles(ii) = h3;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.II0 ))
                II0Gauss = normpdf(XX_II,SubTypeMeans.II0,SubTypeStds.II0);
                
                plot(app.TruePrecisionDistributions,XX_II,II0Gauss, 'k', 'Linewidth',app.lw);
            end
            
            if(any( SubTypeMeans.II1 ))
                II1Gauss = normpdf(XX_II,SubTypeMeans.II1,SubTypeStds.II1);
                
                h5 = plot(app.TruePrecisionDistributions,XX_II,II1Gauss, 'Color',Colors{4}, 'Linewidth',app.lw);
                legendNames{ii} = 'i \rightarrow i';
                handles(ii) = h5;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.II2 ))
                II2Gauss = normpdf(XX_II,SubTypeMeans.II2,SubTypeStds.II2);
                
                h6 = plot(app.TruePrecisionDistributions,XX_II,II2Gauss, 'Color',Colors{5}, 'Linewidth',app.lw);
                legendNames{ii} = 'i \leftrightarrow i';
                handles(ii) = h6;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EIIE0 ))
                EIIE0Gauss = normpdf(XX_EIIE,SubTypeMeans.EIIE0,SubTypeStds.EIIE0);
                
                plot(app.TruePrecisionDistributions,XX_EIIE,EIIE0Gauss, 'k', 'Linewidth',app.lw);

            end
            
            if(any( SubTypeMeans.EI ))
                EIGauss = normpdf(XX_EIIE,SubTypeMeans.EI,SubTypeStds.EI);
                
                h8 = plot(app.TruePrecisionDistributions,XX_EIIE,EIGauss, 'Color',Colors{3}, 'Linewidth',app.lw);
                legendNames{ii} = 'e \rightarrow i';
                handles(ii) = h8;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.IE ))
                IEGauss = normpdf(XX_EIIE,SubTypeMeans.IE,SubTypeStds.IE);
                
                h9 = plot(app.TruePrecisionDistributions,XX_EIIE,IEGauss, 'Color',Colors{6}, 'Linewidth',app.lw);
                legendNames{ii} = 'i \rightarrow e';
                handles(ii) = h9;
                ii = ii + 1;
            end
            
            if(any( SubTypeMeans.EIIE ))
                EIIEGauss = normpdf(XX_EIIE,SubTypeMeans.EIIE,SubTypeStds.EIIE);
                
                h10 = plot(app.TruePrecisionDistributions,XX_EIIE,EIIEGauss, 'Color',Colors{7}, 'Linewidth',app.lw);
                legendNames{ii} = 'e \leftrightarrow i';
                handles(ii) = h10;
            end
            
            app.TruePrecisionDistributions.Box = 'off';
            app.TruePrecisionDistributions.LineWidth = app.lw;
            app.TruePrecisionDistributions.FontSize = app.fz;
            
            legend(app.TruePrecisionDistributions,handles,legendNames, 'location','northwest');
            %objhl = findobj(objh, 'type', 'line'); %// objects of legend of type line
            %set(objhl, 'Markersize', 30);
        end


        % BiDirIds = GetBiDirIds(W,N,Ne)
        %
        % For a blockwise directed adjacency matrix W, return the string Ids
        % of values resulting from the equation
        %       W + W'
        % and thus corresponding to one of 8 groups:
        %       'EE1'-'EE2'-'II1'-'II2'-'EI'-'IE'-'EIIE'-'Z'
        % where 1 or 2 represents unidirectional and bidirectional connections.
        
        % This function is mostly for use on randomly weighted adjacencies, since
        % the 8 groups under static weights form deterministic values...
        
        function GetBiDirIds(app)
            
            temp = 1*(app.W ~= 0);
            
            N = app.NumberofNeuronsEditField.Value;
            Ne = round(N*app.ExcitatoryRatioSlider.Value);
            
            Eids = 1:Ne;
            Iids = (Ne+1):N;
            
            temp(Eids,Eids) = temp(Eids,Eids) * 1;
            temp(Eids,Iids) = temp(Eids,Iids) * 3;
            temp(Iids,Eids) = temp(Iids,Eids) * 5;
            temp(Iids,Iids) = temp(Iids,Iids) * 7;
            
            temp2 = temp + temp';
            temp2( logical(tril(ones(N))) ) = nan;
            
            temp = strings(N,N);
            
            temp(temp2 == 1) = 'EE1';
            temp(temp2 == 2) = 'EE2';
            temp(temp2 == 7) = 'II1';
            temp(temp2 == 14) = 'II2';
            temp(temp2 == 3) = 'EI';
            temp(temp2 == 5) = 'IE';
            temp(temp2 == 8) = 'EIIE';
            
            % assign different zero groups
            Ni = N - Ne;
            IEZMat = nan(Ne,Ni);
            IIZMat = nan(Ni);
            EEZMat = nan(Ne);
            temp2EE = [temp2(Eids,Eids) IEZMat ; IEZMat' IIZMat];
            temp2EI = [EEZMat temp2(Eids,Iids) ; IEZMat' IIZMat];
            temp2II = [EEZMat IEZMat ; IEZMat' temp2(Iids,Iids)];
            temp(temp2EE == 0) = 'EE0';
            temp(temp2EI == 0) = 'EIIE0';
            temp(temp2II == 0) = 'II0';
            
            app.BiDirIds = temp;
        end


        % Assumes 2 pop E/I
        function SubTypeMeans = GetEISubTypeMeans(app)
            
            EE0ids = strcmp(app.BiDirIds,'EE0');
            EE1ids = strcmp(app.BiDirIds,'EE1');
            EE2ids = strcmp(app.BiDirIds,'EE2');
            
            II0ids = strcmp(app.BiDirIds,'II0');
            II1ids = strcmp(app.BiDirIds,'II1');
            II2ids = strcmp(app.BiDirIds,'II2');
            
            EIIE0ids = strcmp(app.BiDirIds,'EIIE0');
            EIids = strcmp(app.BiDirIds,'EI');
            IEids = strcmp(app.BiDirIds,'IE');
            EIIEids = strcmp(app.BiDirIds,'EIIE');
            
            SubTypeMeans = struct();
            
            Mat = app.Precision;
            
            if ( any(EE0ids(:)) )
                Mat_EE0 = Mat(EE0ids);
                SubTypeMeans.EE0 = mean(Mat_EE0);
            end
            
            if ( any(EE1ids(:)) )
                Mat_EE1 = Mat(EE1ids);
                SubTypeMeans.EE1 = mean(Mat_EE1);
            end
            
            if ( any(EE2ids(:)) )
                Mat_EE2 = Mat(EE2ids);
                SubTypeMeans.EE2 = mean(Mat_EE2);
            end
        
            if ( any(II0ids(:)) )
                Mat_II0 = Mat(II0ids);
                SubTypeMeans.II0 = mean(Mat_II0);
            end
            
            if ( any(II1ids(:)) )
                Mat_II1 = Mat(II1ids);
                SubTypeMeans.II1 = mean(Mat_II1);
            end
            
            if ( any(II2ids(:)) )
                Mat_II2 = Mat(II2ids);
                SubTypeMeans.II2 = mean(Mat_II2);
            end
            
            if ( any(EIIE0ids(:)) )
                Mat_EIIE0 = Mat(EIIE0ids);
                SubTypeMeans.EIIE0 = mean(Mat_EIIE0);
            end
            
            if ( any(EIids(:)) )
                Mat_EI = Mat(EIids);
                SubTypeMeans.EI = mean(Mat_EI);
            end
            
            if ( any(IEids(:)) )
                Mat_IE = Mat(IEids);
                SubTypeMeans.IE = mean(Mat_IE);
            end
        
            if ( any(EIIEids(:)) )
                Mat_EIIE = Mat(EIIEids);
                SubTypeMeans.EIIE = mean(Mat_EIIE);
            end
        end
        
        
        % Assumes 2 pop E/I
        
        function SubTypeMeans = GetEISubTypeStds(app)
            
            EE0ids = strcmp(app.BiDirIds,'EE0');
            EE1ids = strcmp(app.BiDirIds,'EE1');
            EE2ids = strcmp(app.BiDirIds,'EE2');
            
            II0ids = strcmp(app.BiDirIds,'II0');
            II1ids = strcmp(app.BiDirIds,'II1');
            II2ids = strcmp(app.BiDirIds,'II2');
            
            EIIE0ids = strcmp(app.BiDirIds,'EIIE0');
            EIids = strcmp(app.BiDirIds,'EI');
            IEids = strcmp(app.BiDirIds,'IE');
            EIIEids = strcmp(app.BiDirIds,'EIIE');
            
            SubTypeMeans = struct();
            
            Mat = app.Precision;
            
            if ( any(EE0ids(:)) )
                Mat_EE0 = Mat(EE0ids);
                SubTypeMeans.EE0 = std(Mat_EE0);
            end
            
            if ( any(EE1ids(:)) )
                Mat_EE1 = Mat(EE1ids);
                SubTypeMeans.EE1 = std(Mat_EE1);
            end
            
            if ( any(EE2ids(:)) )
                Mat_EE2 = Mat(EE2ids);
                SubTypeMeans.EE2 = std(Mat_EE2);
            end
        
            if ( any(II0ids(:)) )
                Mat_II0 = Mat(II0ids);
                SubTypeMeans.II0 = std(Mat_II0);
            end
            
            if ( any(II1ids(:)) )
                Mat_II1 = Mat(II1ids);
                SubTypeMeans.II1 = std(Mat_II1);
            end
            
            if ( any(II2ids(:)) )
                Mat_II2 = Mat(II2ids);
                SubTypeMeans.II2 = std(Mat_II2);
            end
            
            if ( any(EIIE0ids(:)) )
                Mat_EIIE0 = Mat(EIIE0ids);
                SubTypeMeans.EIIE0 = std(Mat_EIIE0);
            end
            
            if ( any(EIids(:)) )
                Mat_EI = Mat(EIids);
                SubTypeMeans.EI = std(Mat_EI);
            end
            
            if ( any(IEids(:)) )
                Mat_IE = Mat(IEids);
                SubTypeMeans.IE = std(Mat_IE);
            end
        
            if ( any(EIIEids(:)) )
                Mat_EIIE = Mat(EIIEids);
                SubTypeMeans.EIIE = std(Mat_EIIE);
            end
        end
        
        
        % Plot unlabeled ROC
        function GetConnTypeROC(app)
            N = app.NumberofNeuronsEditField.Value;
            if (N > 5e3)
                nUse = 5e3; % hardcoded for eps output
            else
                nUse = N;
            end
            
            ConnTypeValues = GetConnTypeValues(app,nUse);
            
            if (any(ConnTypeValues.Conn))
                [X,Y,T,AUROC] = perfcurve([ones(numel(ConnTypeValues.Conn),1); ...
                                           zeros(numel(ConnTypeValues.Null),1)], ...
                                                                [ConnTypeValues.Conn; ConnTypeValues.Null],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                
                ROC = struct();
                ROC.X = X;
                ROC.Y = Y;
                ROC.T = T;
                ROC.AUROC = AUROC;
                
                app.AUROCUnlabeled.Value = ROC.AUROC;
        
                plot(app.ROCUnknown,ROC.X,ROC.Y, 'k-', 'Linewidth',app.lw)
                hold(app.ROCUnknown,'on')
                plot(app.ROCUnknown,0:1,0:1, 'r--', 'Linewidth',app.lw)
                app.ROCUnknown.Box = 'off';
                app.ROCUnknown.LineWidth = app.lw;
                app.ROCUnknown.FontSize = app.fz;
            else
                ROC = [];
            end
        end
        
        
        % Plot labeled ROC
        function GetEITypeROCs(app)
            N = app.NumberofNeuronsEditField.Value;
            if (N > 5e3)
                nUse = 5e3; % hardcoded for eps output
            else
                nUse = N;
            end
            
            SubTypeValues = GetEISubTypeValues(app,nUse);
            
            SubTypeROCs = struct();
            
            if (any(SubTypeValues.EE1))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.EE1),1); zeros(numel(SubTypeValues.EE0),1)], ...
                                                                                 [SubTypeValues.EE1; SubTypeValues.EE0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                
                EE1vsEE0 = struct();
                EE1vsEE0.X = X;
                EE1vsEE0.Y = Y;
                EE1vsEE0.T = T;
                EE1vsEE0.AUROC = AUROC;
                SubTypeROCs.EE1vsEE0 = EE1vsEE0;
            else
                SubTypeROCs.EE1vsEE0 = [];
            end
            
            if (any(SubTypeValues.EE2))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.EE2),1); zeros(numel(SubTypeValues.EE0),1)], ...
                                                                                 [SubTypeValues.EE2; SubTypeValues.EE0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                
                EE2vsEE0 = struct();
                EE2vsEE0.X = X;
                EE2vsEE0.Y = Y;
                EE2vsEE0.T = T;
                EE2vsEE0.AUROC = AUROC;
                SubTypeROCs.EE2vsEE0 = EE2vsEE0;
            else
                SubTypeROCs.EE2vsEE0 = [];
            end
            
            if (any(SubTypeValues.EE2))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.EE2),1); zeros(numel(SubTypeValues.EE1),1)], ...
                                                                                 [SubTypeValues.EE2; SubTypeValues.EE1],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                
                EE2vsEE1 = struct();
                EE2vsEE1.X = X;
                EE2vsEE1.Y = Y;
                EE2vsEE1.T = T;
                EE2vsEE1.AUROC = AUROC;
                SubTypeROCs.EE2vsEE1 = EE2vsEE1;
            else
                SubTypeROCs.EE2vsEE1 = [];
            end
            
            if (any(SubTypeValues.II1))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.II1),1); zeros(numel(SubTypeValues.II0),1)], ...
                                                                                 [SubTypeValues.II1; SubTypeValues.II0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                
                II1vsII0 = struct();
                II1vsII0.X = X;
                II1vsII0.Y = Y;
                II1vsII0.T = T;
                II1vsII0.AUROC = AUROC;
                SubTypeROCs.II1vsII0 = II1vsII0;
            else
                SubTypeROCs.II1vsII0 = [];
            end
            
            if (any(SubTypeValues.II2))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.II2),1); zeros(numel(SubTypeValues.II0),1)], ...
                                                                                 [SubTypeValues.II2; SubTypeValues.II0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                II2vsII0 = struct();
                II2vsII0.X = X;
                II2vsII0.Y = Y;
                II2vsII0.T = T;
                II2vsII0.AUROC = AUROC;
                SubTypeROCs.II2vsII0 = II2vsII0;
            else
                SubTypeROCs.II2vsII0 = [];
            end
            
            if (any(SubTypeValues.II2))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.II2),1); zeros(numel(SubTypeValues.II1),1)], ...
                                                                                 [SubTypeValues.II2; SubTypeValues.II1],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                II2vsII1 = struct();
                II2vsII1.X = X;
                II2vsII1.Y = Y;
                II2vsII1.T = T;
                II2vsII1.AUROC = AUROC;
                SubTypeROCs.II2vsII1 = II2vsII1;
            else
                SubTypeROCs.II2vsII1 = [];
            end
            
            if (any(SubTypeValues.EI))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.EI),1); zeros(numel(SubTypeValues.EIIE0),1)], ...
                                                                                [SubTypeValues.EI; SubTypeValues.EIIE0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                EIvsEIIE0 = struct();
                EIvsEIIE0.X = X;
                EIvsEIIE0.Y = Y;
                EIvsEIIE0.T = T;
                EIvsEIIE0.AUROC = AUROC;
                SubTypeROCs.EIvsEIIE0 = EIvsEIIE0;
            else
                SubTypeROCs.EIvsEIIE0 = [];
            end
            
            if (any(SubTypeValues.IE))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.IE),1); zeros(numel(SubTypeValues.EIIE0),1)], ...
                                                                                [SubTypeValues.IE; SubTypeValues.EIIE0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                IEvsEIIE0 = struct();
                IEvsEIIE0.X = X;
                IEvsEIIE0.Y = Y;
                IEvsEIIE0.T = T;
                IEvsEIIE0.AUROC = AUROC;
                SubTypeROCs.IEvsEIIE0 = IEvsEIIE0;
            else
                SubTypeROCs.IEvsEIIE0 = [];
            end
            
            if (any(SubTypeValues.EIIE))
                [X,Y,T,AUROC] = perfcurve([ones(numel(SubTypeValues.EIIE),1); zeros(numel(SubTypeValues.EIIE0),1)], ...
                                                                              [SubTypeValues.EIIE; SubTypeValues.EIIE0],1);
                if (AUROC < 0.5)
                    X = 1 - X;
                    Y = 1 - Y;
                    AUROC = 1 - AUROC;
                end
                EIIEvsEIIE0 = struct();
                EIIEvsEIIE0.X = X;
                EIIEvsEIIE0.Y = Y;
                EIIEvsEIIE0.T = T;
                EIIEvsEIIE0.AUROC = AUROC;
                SubTypeROCs.EIIEvsEIIE0 = EIIEvsEIIE0;
            else
                SubTypeROCs.EIIEvsEIIE0 = [];
            end
        
            Colors = {[0 0.6 0], ... % EE1
                      [0 0.8 0], ... % EE2
                      [0.3010 0.7450 0.9330], ... % IE
                      [0.6 0 0], ... % II1
                      [0.8 0 0], ... % II2
                      [0.4940 0.1840 0.5560], ... % EI
                      [0 0.4470 0.7410]}; % EIIE
            
            if (isstruct(SubTypeROCs.EE1vsEE0))
                plot(app.ROCKnown,SubTypeROCs.EE1vsEE0.X,SubTypeROCs.EE1vsEE0.Y, 'Linewidth',app.lw, 'Color',Colors{1})
                hold(app.ROCKnown,'on')
                app.AUROCEE1.Value = SubTypeROCs.EE1vsEE0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.EE2vsEE0))
                plot(app.ROCKnown,SubTypeROCs.EE2vsEE0.X,SubTypeROCs.EE2vsEE0.Y, 'Linewidth',app.lw, 'Color',Colors{2})
                hold(app.ROCKnown,'on')
                app.AUROCEE2.Value = SubTypeROCs.EE2vsEE0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.IEvsEIIE0))
                plot(app.ROCKnown,SubTypeROCs.IEvsEIIE0.X,SubTypeROCs.IEvsEIIE0.Y, 'Linewidth',app.lw, 'Color',Colors{3})
                hold(app.ROCKnown,'on')
                app.AUROCIE.Value = SubTypeROCs.IEvsEIIE0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.II1vsII0))
                plot(app.ROCKnown,SubTypeROCs.II1vsII0.X,SubTypeROCs.II1vsII0.Y, 'Linewidth',app.lw, 'Color',Colors{4})
                hold(app.ROCKnown,'on')
                app.AUROCII1.Value = SubTypeROCs.II1vsII0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.II2vsII0))
                plot(app.ROCKnown,SubTypeROCs.II2vsII0.X,SubTypeROCs.II2vsII0.Y, 'Linewidth',app.lw, 'Color',Colors{5})
                hold(app.ROCKnown,'on')
                app.AUROCII2.Value = SubTypeROCs.II2vsII0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.EIvsEIIE0))
                plot(app.ROCKnown,SubTypeROCs.EIvsEIIE0.X,SubTypeROCs.EIvsEIIE0.Y, 'Linewidth',app.lw, 'Color',Colors{6})
                hold(app.ROCKnown,'on')
                app.AUROCEI.Value = SubTypeROCs.EIvsEIIE0.AUROC;
            end
            
            if (isstruct(SubTypeROCs.EIIEvsEIIE0))
                plot(app.ROCKnown,SubTypeROCs.EIIEvsEIIE0.X,SubTypeROCs.EIIEvsEIIE0.Y, 'Linewidth',app.lw, 'Color',Colors{7})
                hold(app.ROCKnown,'on')
                app.AUROCEIIE.Value = SubTypeROCs.EIIEvsEIIE0.AUROC;
            end

            hold(app.ROCKnown,'on')
            plot(app.ROCKnown,0:1,0:1, 'r--', 'Linewidth',app.lw)
            app.ROCKnown.Box = 'off';
            app.ROCKnown.LineWidth = app.lw;
            app.ROCKnown.FontSize = app.fz;
        end


        % Helper for unlabeled ROC
        function [SubTypeValues,indsOut] = GetConnTypeValues(app,nNeurPlot_or_indsIn)
            
            N = app.NumberofNeuronsEditField.Value;
            
            if (nargin == 3) % nNeurPlot_or_indsIn passed
                if ( all(size(nNeurPlot_or_indsIn) == 1) ) % nNeurPlot passed
                    qe = find(strcmp(app.BiDirIds(1,:),'EIIE0'),1) / N; % technically not exact estimate
                    Ne = round(qe*N);
                    Ni = round((1-qe)*N);
        
                    if(N > 5e2)
                        % Try to split nNeurPlot evenly between E/I
                        if (nNeurPlot_or_indsIn/2 <= Ni)
                            inds = [randperm(Ne,nNeurPlot_or_indsIn/2) randperm(Ni,nNeurPlot_or_indsIn/2)+Ne];
                        else
                            inds = [randperm(Ne,nNeurPlot_or_indsIn-Ni) (Ne+1):N];
                        end
                    else
                        inds = 1:N;
                    end
                else % inds passed
                    inds = nNeurPlot_or_indsIn;
                end
            else % nNeurPlot_or_indsIn not passed
                inds = 1:N;
            end
            
            Mat = app.Precision(inds,inds);
            app.BiDirIds = app.BiDirIds(inds,inds);
            
            indsOut = inds;
            
            NullIds = strcmp(app.BiDirIds,'EE0') | strcmp(app.BiDirIds,'II0') | strcmp(app.BiDirIds,'EIIE0');
            ConnIds = strcmp(app.BiDirIds,'EE1') | strcmp(app.BiDirIds,'EE2') | strcmp(app.BiDirIds,'II1') ...
                      | strcmp(app.BiDirIds,'II2') | strcmp(app.BiDirIds,'EI') | strcmp(app.BiDirIds,'IE') ...
                      | strcmp(app.BiDirIds,'EIIE');
            
            SubTypeValues = struct();
            
            if ( any(NullIds(:)) )
                Mat_Null = Mat(NullIds);
                SubTypeValues.Null = Mat_Null;
            else
                SubTypeValues.Null = [];
            end
            
            if ( any(ConnIds(:)) )
                Mat_Conn = Mat(ConnIds);
                SubTypeValues.Conn = Mat_Conn;
            else
                SubTypeValues.Conn = [];
            end
        end


        % Assumes 2 pop E/I
        function [SubTypeValues,indsOut] = GetEISubTypeValues(app,nNeurPlot_or_indsIn)
            
            N = app.NumberofNeuronsEditField.Value;
            
            if (nargin == 3) % nNeurPlot_or_indsIn passed
                if ( all(size(nNeurPlot_or_indsIn) == 1) ) % nNeurPlot passed
                    qe = find(strcmp(app.BiDirIds(1,:),'EIIE0'),1) / N; % technically not exact estimate
                    Ne = round(qe*N);
                    Ni = round((1-qe)*N);
        
                    if(N > 5e2)
                        % Try to split nNeurPlot evenly between E/I
                        if (nNeurPlot_or_indsIn/2 <= Ni)
                            inds = [randperm(Ne,nNeurPlot_or_indsIn/2) randperm(Ni,nNeurPlot_or_indsIn/2)+Ne];
                        else
                            inds = [randperm(Ne,nNeurPlot_or_indsIn-Ni) (Ne+1):N];
                        end
                    else
                        inds = 1:N;
                    end
                else % inds passed
                    inds = nNeurPlot_or_indsIn;
                end
            else % nNeurPlot_or_indsIn not passed
                inds = 1:N;
            end
            
            Mat = app.Precision(inds,inds);
            app.BiDirIds = app.BiDirIds(inds,inds);
            
            indsOut = inds;
            
            EE0ids = strcmp(app.BiDirIds,'EE0');
            EE1ids = strcmp(app.BiDirIds,'EE1');
            EE2ids = strcmp(app.BiDirIds,'EE2');
            
            II0ids = strcmp(app.BiDirIds,'II0');
            II1ids = strcmp(app.BiDirIds,'II1');
            II2ids = strcmp(app.BiDirIds,'II2');
            
            EIIE0ids = strcmp(app.BiDirIds,'EIIE0');
            EIids = strcmp(app.BiDirIds,'EI');
            IEids = strcmp(app.BiDirIds,'IE');
            EIIEids = strcmp(app.BiDirIds,'EIIE');
            
            SubTypeValues = struct();
            
            if ( any(EE0ids(:)) )
                Mat_EE0 = Mat(EE0ids);
                SubTypeValues.EE0 = Mat_EE0;
            else
                SubTypeValues.EE0 = [];
            end
            
            if ( any(EE1ids(:)) )
                Mat_EE1 = Mat(EE1ids);
                SubTypeValues.EE1 = Mat_EE1;
            else
                SubTypeValues.EE1 = [];
            end
            
            if ( any(EE2ids(:)) )
                Mat_EE2 = Mat(EE2ids);
                SubTypeValues.EE2 = Mat_EE2;
            else
                SubTypeValues.EE2 = [];
            end
        
            if ( any(II0ids(:)) )
                Mat_II0 = Mat(II0ids);
                SubTypeValues.II0 = Mat_II0;
            else
                SubTypeValues.II0 = [];
            end
            
            if ( any(II1ids(:)) )
                Mat_II1 = Mat(II1ids);
                SubTypeValues.II1 = Mat_II1;
            else
                SubTypeValues.II1 = [];
            end
            
            if ( any(II2ids(:)) )
                Mat_II2 = Mat(II2ids);
                SubTypeValues.II2 = Mat_II2;
            else
                SubTypeValues.II2 = [];
            end
            
            if ( any(EIIE0ids(:)) )
                Mat_EIIE0 = Mat(EIIE0ids);
                SubTypeValues.EIIE0 = Mat_EIIE0;
            else
                SubTypeValues.EIIE0 = [];
            end
            
            if ( any(EIids(:)) )
                Mat_EI = Mat(EIids);
                SubTypeValues.EI = Mat_EI;
            else
                SubTypeValues.EI = [];
            end
            
            if ( any(IEids(:)) )
                Mat_IE = Mat(IEids);
                SubTypeValues.IE = Mat_IE;
            else
                SubTypeValues.IE = [];
            end
        
            if ( any(EIIEids(:)) )
                Mat_EIIE = Mat(EIIEids);
                SubTypeValues.EIIE = Mat_EIIE;
            else
                SubTypeValues.EIIE = [];
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
              
            % Resize window with dynamic resolution
            screenSize = get(groot,'ScreenSize');
            screenWidth = screenSize(3);
            screenHeight = screenSize(4);
            left = screenWidth*0.1;
            bottom = screenHeight*0.1;
            width = screenWidth*0.5;
            height = screenHeight*0.8;
            drawnow;
            app.UIFigure.Position = [left bottom width height];
            
            % Generate default network and plots
            switch app.RandomGraphModelDropDown.Value
                case 'Erdos-Renyi'
                    GenerateERNetwork(app);
            end
            
            PlotHeatmap(app)
            PlotPrecisionDistributions(app)
            GetConnTypeROC(app)
            GetEITypeROCs(app)
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            switch app.RandomGraphModelDropDown.Value
                case 'Erdos-Renyi'
                    GenerateERNetwork(app);
            end
            
            PlotHeatmap(app)
            PlotPrecisionDistributions(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.NetworkParametersandGroundTruthTab = uitab(app.TabGroup);
            app.NetworkParametersandGroundTruthTab.Title = 'Network Parameters and Ground Truth';
            app.ROCKnown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCKnown, 'ROC - Known Cell Types')
            xlabel(app.ROCKnown, 'FPR')
            ylabel(app.ROCKnown, 'TPR')
            app.TruePrecisionDistributions = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.TruePrecisionDistributions, 'True Precision Distributions')
            xlabel(app.TruePrecisionDistributions, 'Precision')
            ylabel(app.TruePrecisionDistributions, 'Density')
            app.Heatmap = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.Heatmap, 'Heatmap of Connectivity')
            xlabel(app.Heatmap, 'Neuron Index')
            ylabel(app.Heatmap, 'Neuron Index')
            app.ROCUnknown = uiaxes(app.NetworkParametersandGroundTruthTab);
            title(app.ROCUnknown, 'ROC - Unknown Cell Types')
            xlabel(app.ROCUnknown, 'FPR')
            ylabel(app.ROCUnknown, 'TPR')
            app.SynapticMagnitudeSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticMagnitudeSliderLabel.Text = 'Synaptic Magnitude';
            app.SynapticMagnitudeSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.SynapticMagnitudeSlider.Limits = [0 10];
            app.SynapticMagnitudeSlider.Value = 2.5;
            app.SynapticVariabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySliderLabel.Text = 'Synaptic Variability';
            app.SynapticVariabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.SynapticVariabilitySlider.Limits = [0 1];
            app.SynapticVariabilitySlider.Value = 6.24e-05;
            app.RandomGraphModelDropDownLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDownLabel.Text = 'Random Graph Model';
            app.RandomGraphModelDropDown = uidropdown(app.NetworkParametersandGroundTruthTab);
            app.RandomGraphModelDropDown.Items = {'Erdos-Renyi', 'Spatial'};
            app.RandomGraphModelDropDown.Value = 'Erdos-Renyi';
            app.GenerateButton = uibutton(app.NetworkParametersandGroundTruthTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.NumberofNeuronsEditFieldLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.NumberofNeuronsEditFieldLabel.Text = 'Number of Neurons';
            app.NumberofNeuronsEditField = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.NumberofNeuronsEditField.Value = 100;
            app.ConnectionProbabilitySliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySliderLabel.Text = 'Connection Probability';
            app.ConnectionProbabilitySlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ConnectionProbabilitySlider.Limits = [0 1];
            app.ConnectionProbabilitySlider.MajorTicks = [0 0.2 0.4 0.6 0.8 1];
            app.ConnectionProbabilitySlider.MajorTickLabels = {'0', '0.2', '0.4', '0.6', '0.8', '1'};
            app.ConnectionProbabilitySlider.MinorTicks = [0 0.04 0.08 0.12 0.16 0.2 0.24 0.28 0.32 0.36 0.4 0.44 0.48 0.52 0.56 0.6 0.64 0.68 0.72 0.76 0.8 0.84 0.88 0.92 0.96 1];
            app.ConnectionProbabilitySlider.Value = 0.2;
            app.ExcitatoryRatioSliderLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSliderLabel.Text = 'Excitatory Ratio';
            app.ExcitatoryRatioSlider = uislider(app.NetworkParametersandGroundTruthTab);
            app.ExcitatoryRatioSlider.Limits = [0 1];
            app.ExcitatoryRatioSlider.Value = 0.8;
            app.SynapticRatiosEditFieldLabel_2 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_2.Text = 'e';
            app.SynapticRatiosEditFieldLabel_3 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_3.Text = 'i';
            app.SynapticRatiosEditFieldLabel_4 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_4.Text = 'e';
            app.SynapticRatiosEditFieldLabel_5 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_5.Text = 'i';
            app.SynapticRatiosEditFieldLabel_6 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.SynapticRatiosEditFieldLabel_6.Text = 'Synaptic Ratios';
            app.SynapticRatiosEE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosEE.Value = 0.1;
            app.SynapticRatiosIE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosIE.Value = 0.45;
            app.SynapticRatiosEI = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.SynapticRatiosEI.Value = 0.6;
            app.AreaUnderEditFieldLabel = uilabel(app.NetworkParametersandGroundTruthTab);
            app.AreaUnderEditFieldLabel.Text = 'Area Under';
            app.AUROCEE1 = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AreaUnderEditFieldLabel_2 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.AreaUnderEditFieldLabel_2.Text = 'ROC Curves';
            app.AUROCEE2 = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCII1 = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCII2 = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCEI = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCIE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCEIIE = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AUROCUnlabeled = uieditfield(app.NetworkParametersandGroundTruthTab, 'numeric');
            app.AreaUnderEditFieldLabel_3 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.AreaUnderEditFieldLabel_3.Text = 'Area Under';
            app.AreaUnderEditFieldLabel_4 = uilabel(app.NetworkParametersandGroundTruthTab);
            app.AreaUnderEditFieldLabel_4.Text = 'ROC Curve';
            app.SimulationTab = uitab(app.TabGroup);
            app.SimulationTab.Title = 'Simulation';
            app.AnalysisTab = uitab(app.TabGroup);
            app.AnalysisTab.Title = 'Analysis';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = InferencePaperApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end